classdef Consent4_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        text2          matlab.ui.control.Label
        edit1          matlab.ui.control.EditField
        edit2          matlab.ui.control.EditField
        text3          matlab.ui.control.Label
        edit3          matlab.ui.control.EditField
        text6          matlab.ui.control.Label
        text5          matlab.ui.control.Label
        edit5          matlab.ui.control.EditField
        edit6          matlab.ui.control.EditField
        edit7          matlab.ui.control.EditField
        text7          matlab.ui.control.Label
        TextArea       matlab.ui.control.TextArea
        SuivantButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Consent4_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to Consent4 (see VARARGIN)
%             
%             % Choose default command line output for Consent4
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
            global Consent_Vars
            Consent_Vars.Name(1) = {app.edit1.Value};
        end

        % Value changed function: edit2
        function edit2_Callback(app, event)
            global Consent_Vars
            Consent_Vars.Name(2) = {app.edit2.Value};
        end

        % Value changed function: edit3
        function edit3_Callback(app, event)
            global Consent_Vars
            Consent_Vars.DDN = app.edit3.Value;
        end

        % Value changed function: edit5
        function edit5_Callback(app, event)
            % hObject    handle to edit5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit5 as text
            %        str2double(get(hObject,'String')) returns contents of edit5 as a double
            global Consent_Vars
            Consent_Vars.Consent(1) = {app.edit5.Value};
        end

        % Value changed function: edit6
        function edit6_Callback(app, event)
            global Consent_Vars
            Consent_Vars.Consent(2) = {app.edit6.Value};
        end

        % Value changed function: edit7
        function edit7_Callback(app, event)
            global Consent_Vars
            Consent_Vars.Consent(3) = {app.edit7.Value};
            Consent_Vars.CodeName = cell2mat(strcat(Consent_Vars.Consent(3), char(datetime(Consent_Vars.DDN,'Format', 'ddMM'))));
        end

        % Button pushed function: SuivantButton
        function SuivantButtonPushed(app, event)
            close(app.figure1)
            Consent5_App();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Consent';
            app.text2 = uilabel(app.figure1);
            app.text2.Text = 'Je soussigné(e) (Nom, Prénom): ';
            app.edit1 = uieditfield(app.figure1, 'text');
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            app.edit2 = uieditfield(app.figure1, 'text');
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit2_Callback, true);
            app.text3 = uilabel(app.figure1);
            app.text3.Text = 'Né(e) le (jj/mm/aaaa):';
            app.edit3 = uieditfield(app.figure1, 'text');
            app.edit3.ValueChangedFcn = createCallbackFcn(app, @edit3_Callback, true);
            app.text5 = uilabel(app.figure1);
            app.text5.Text = 'Fait à:';
            app.edit5 = uieditfield(app.figure1, 'text');
            app.edit5.ValueChangedFcn = createCallbackFcn(app, @edit5_Callback, true);
            app.text6 = uilabel(app.figure1);
            app.text6.Text = 'Le:';
            app.edit6 = uieditfield(app.figure1, 'text');
            app.edit6.ValueChangedFcn = createCallbackFcn(app, @edit6_Callback, true);
            app.edit7 = uieditfield(app.figure1, 'text');
            app.edit7.ValueChangedFcn = createCallbackFcn(app, @edit7_Callback, true);
            app.text7 = uilabel(app.figure1);
            app.text7.Text = 'Initiales:';
            app.SuivantButton = uibutton(app.figure1, 'push');
            app.SuivantButton.ButtonPushedFcn = createCallbackFcn(app, @SuivantButtonPushed, true);
            app.SuivantButton.Text = 'Suivant';
            app.TextArea = uitextarea(app.figure1);
            app.TextArea.Value = {'déclare accepter participer à la recherche intitulée: '; ' '; '"Modeling Shift from Efficient to Inefficient divided attention" (CERNI n° 020_322_Dehais) '; ' '; 'organisée par le Pr F. DEHAIS (ISAE, 31055, Toulouse, tel: 05 61 33 83 72) dans les conditions précisées ci-dessous et pour laquelle le CEEI a émis un avis favorable le 17/11/2020.'; ''; ''; 'J’ai lu la notice d’information qui m’a été remise. J’ai pu poser toutes les questions que je voulais et j’ai reçu des réponses adaptées. Il m’a été précisé que:'; ''; '•	Je suis libre d’accepter ou de refuser ainsi que d’arrêter à tout moment ma participation. '; '•	J’ai pu disposer d’un temps de réflexion suffisant entre l’information et le consentement '; '•	Les données qui me concernent resteront strictement confidentielles. Je n’autorise leur consultation que par des personnes qui collaborent avec les Pr DEHAIS. Je pourrai à tout moment leur demander des informations et je pourrai exercer mon droit d’accès, de rectification ou d’opposition.'; '•	La publication des résultats de la recherche ne comportera aucun résultat individuel identifiant.'; '•	Je peux être tenu au courant des résultats globaux de la recherche en m’adressant au Pr DEHAIS. '; '•	Mon consentement ne décharge pas les organisateurs de la recherche de leurs responsabilités. Je conserve tous mes droits garantis par la loi. '; ''; 'J’accepte de façon libre et volontaire de participer à la présente recherche.'};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Consent4_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Consent4_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end