classdef Consent5_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        uibuttongroup1  matlab.ui.container.ButtonGroup
        checkbox1       matlab.ui.control.CheckBox
        checkbox3       matlab.ui.control.CheckBox
        checkbox4       matlab.ui.control.CheckBox
        checkbox5       matlab.ui.control.CheckBox
        text3           matlab.ui.control.Label
        uibuttongroup3  matlab.ui.container.ButtonGroup
        checkbox6       matlab.ui.control.CheckBox
        checkbox7       matlab.ui.control.CheckBox
        checkbox8       matlab.ui.control.CheckBox
        checkbox9       matlab.ui.control.CheckBox
        checkbox10      matlab.ui.control.CheckBox
        text4           matlab.ui.control.Label
        text5           matlab.ui.control.Label
        uibuttongroup4  matlab.ui.container.ButtonGroup
        checkbox18      matlab.ui.control.CheckBox
        checkbox19      matlab.ui.control.CheckBox
        checkbox20      matlab.ui.control.CheckBox
        checkbox21      matlab.ui.control.CheckBox
        checkbox22      matlab.ui.control.CheckBox
        checkbox23      matlab.ui.control.CheckBox
        checkbox24      matlab.ui.control.CheckBox
        uibuttongroup5  matlab.ui.container.ButtonGroup
        radiobutton2    matlab.ui.control.RadioButton
        radiobutton3    matlab.ui.control.RadioButton
        text7           matlab.ui.control.Label
        uibuttongroup7  matlab.ui.container.ButtonGroup
        radiobutton5    matlab.ui.control.RadioButton
        radiobutton6    matlab.ui.control.RadioButton
        SuivantButton   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Consent5_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to Consent5 (see VARARGIN)
%             
%             % Choose default command line output for Consent5
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Button pushed function: SuivantButton
        function SuivantButtonPushed(app, event)
            global Consent_Vars
            Consent_Vars.Scholar{3} = [{app.uibuttongroup4.Children.Text};{app.uibuttongroup4.Children.Value}];
            Consent_Vars.Scholar{2} = [{app.uibuttongroup3.Children.Text};{app.uibuttongroup3.Children.Value}];
            Consent_Vars.Scholar{1} = [{app.uibuttongroup1.Children.Text};{app.uibuttongroup1.Children.Value}];
            Consent_Vars.Scholar{4} = [{app.uibuttongroup5.Children.Text};{app.uibuttongroup5.Children.Value}];
            Consent_Vars.Scholar{5} = [{app.uibuttongroup7.Children.Text};{app.uibuttongroup7.Children.Value}];
            close(app.figure1)
            Consent6_App();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Consent';
            app.uibuttongroup1 = uibuttongroup(app.figure1);
            app.uibuttongroup1.Title = 'Niveau Scolaire';
            app.checkbox1 = uicheckbox(app.uibuttongroup1);
            app.checkbox1.Text = 'Bac ou moins';
            app.checkbox3 = uicheckbox(app.uibuttongroup1);
            app.checkbox3.Text = 'Bac+3';
            app.checkbox4 = uicheckbox(app.uibuttongroup1);
            app.checkbox4.Text = 'Bac+5';
            app.checkbox5 = uicheckbox(app.uibuttongroup1);
            app.checkbox5.Text = 'Bac+8';
            app.text3 = uilabel(app.figure1);
            app.text3.Text = 'Propension à jouer aux jeux vidéo';
            app.uibuttongroup3 = uibuttongroup(app.figure1);
            app.checkbox6 = uicheckbox(app.uibuttongroup3);
            app.checkbox6.Text = '0 fois';
            app.checkbox7 = uicheckbox(app.uibuttongroup3);
            app.checkbox7.Text = '1 à 10 fois (~1 fois par mois)';
            app.checkbox8 = uicheckbox(app.uibuttongroup3);
            app.checkbox8.Text = '11 à 30 fois (~2 fois par mois)';
            app.checkbox9 = uicheckbox(app.uibuttongroup3);
            app.checkbox9.Text = '31 à 60 fois (3 à 5 fois par mois)';
            app.checkbox10 = uicheckbox(app.uibuttongroup3);
            app.checkbox10.Text = '>60 fois';
            app.text4 = uilabel(app.figure1);
            app.text4.Text = {'Dans les 12 derniers mois, combien de fois avez-vous lancé un jeu vidéo (sur PC,'; ' console de salon, console portable, smartphone, arcade, etc.)?'};
            app.text5 = uilabel(app.figure1);
            app.text5.Text = {'Actuellement, combien de temps passez-vous à jouer aux jeux vidéo par jour '; 'en moyenne ?'};
            app.uibuttongroup4 = uibuttongroup(app.figure1);
            app.checkbox18 = uicheckbox(app.uibuttongroup4);
            app.checkbox18.Text = '0 heures';
            app.checkbox19 = uicheckbox(app.uibuttongroup4);
            app.checkbox19.Text = '0-30 minutes';
            app.checkbox20 = uicheckbox(app.uibuttongroup4);
            app.checkbox20.Text = '30-60 minutes';
            app.checkbox21 = uicheckbox(app.uibuttongroup4);
            app.checkbox21.Text = '1 à 2 heures';
            app.checkbox22 = uicheckbox(app.uibuttongroup4);
            app.checkbox22.Text = '2 à 3 heures';
            app.checkbox23 = uicheckbox(app.uibuttongroup4);
            app.checkbox23.Text = '3 à 4 heures';
            app.checkbox24 = uicheckbox(app.uibuttongroup4);
            app.checkbox24.Text = '>4 heures';
            app.uibuttongroup5 = uibuttongroup(app.figure1);
            app.uibuttongroup5.Title = 'Vous considérez-vous comme un joueur de jeux vidéo actif? ';
            app.radiobutton2 = uiradiobutton(app.uibuttongroup5);
            app.radiobutton2.Text = 'Oui';
            app.radiobutton3 = uiradiobutton(app.uibuttongroup5);
            app.radiobutton3.Text = 'Non';
            app.radiobutton3.Value = true;
            app.text7 = uilabel(app.figure1);
            app.text7.Text = {'Avez-vous eu des périodes dans votre vie où vous avez joué intensivement '; '(plus de 2 heures par jour en moyenne, pendant au moins 3 mois) à des jeux vidéo?'};
            app.uibuttongroup7 = uibuttongroup(app.figure1);
            app.radiobutton5 = uiradiobutton(app.uibuttongroup7);
            app.radiobutton5.Text = 'Oui';
            app.radiobutton6 = uiradiobutton(app.uibuttongroup7);
            app.radiobutton6.Text = 'Non';
            app.radiobutton6.Value = true;
            app.SuivantButton = uibutton(app.figure1, 'push');
            app.SuivantButton.ButtonPushedFcn = createCallbackFcn(app, @SuivantButtonPushed, true);
            app.SuivantButton.Text = 'Suivant';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Consent5_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Consent5_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end