classdef Consent6_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        pushbutton2     matlab.ui.control.Button
        TextArea        matlab.ui.control.TextArea
        TextArea_2      matlab.ui.control.TextArea
        Slider          matlab.ui.control.Slider
        TextArea_3      matlab.ui.control.TextArea
        UITable         matlab.ui.control.Table
        uibuttongroup1  matlab.ui.container.ButtonGroup
        checkbox1       matlab.ui.control.CheckBox
        checkbox3       matlab.ui.control.CheckBox
        checkbox4       matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Read table array from file
            t = readtable('Laterality.xls');
            vars = {'Var1', 'Var2','G','D'};
            
            % Select a subset of the table array
            t = t(1:13,vars);
            t.G = zeros(13,1);
            t.D = zeros(13,1);
            
            % Add data to the Table UI Component
            app.UITable.Data = t;
            app.UITable.ColumnName = {'', 'Quelle main utilisez-vous préférentiellement pour:', 'G', 'D'};
            app.UITable.ColumnEditable = true;
        end

        % Button pushed function: pushbutton2
        function pushbutton2ButtonPushed(app, event)
            idx = cell2mat({app.uibuttongroup1.Children.Value});
            Genre = {app.uibuttongroup1.Children.Text};
            Genre = Genre(idx);
            global Consent_Vars
            Consent_Vars.Genre = Genre;
            Consent_Vars.Lateralite = sum(app.UITable.Data.D)-sum(app.UITable.Data.G);
            Consent_Vars.KSS = app.Slider.Value;
            filename = ['.\Consent_forms\', Consent_Vars.CodeName, '_', datestr(datenum(Consent_Vars.Consent(2), 'dd/mm/yyyy'), 'yyyymmdd'),'.mat'];
            save(filename, 'Consent_Vars')
            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.pushbutton2 = uibutton(app.UIFigure, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2ButtonPushed, true);
            app.pushbutton2.Text = 'Suivant';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Genre, latéralité et niveau de vigilance'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Veuillez indiquer votre état de vigilance à ce moment précis, en sélectionnant parmi les états suivants celui celui se raprochant le plus de votre état. Vous pouvez aussi utiliser les états intermédiaires.'};
            app.Slider = uislider(app.UIFigure);
            app.Slider.MajorTicks = [0 25 50 75 100];
            app.Slider.MajorTickLabels = {'Très éveillé(e)', 'Eveillé(e) - niveau normal', 'Ni éveillé(e), ni somnolent(e)', 'Somnolent(e)', 'Très somnolent(e)/Fatigué(e)'};
            app.Slider.MinorTicks = [12.5 37.5 62.5 87.5];
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'Indiquez quelle main vous utiliseriez préférentiellement pour faires les activités ci-dessous en inscrivant un « 1 » dans la colonne appropriée. Si vous êtes indifférent à la latéralité pour l’une des activités, inscrivez un « 1 » dans chaque colonne.'};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.uibuttongroup1 = uibuttongroup(app.UIFigure);
            app.uibuttongroup1.Title = 'Genre';
            app.checkbox1 = uicheckbox(app.uibuttongroup1);
            app.checkbox1.Text = 'Femme';
            app.checkbox3 = uicheckbox(app.uibuttongroup1);
            app.checkbox3.Text = 'Homme';
            app.checkbox4 = uicheckbox(app.uibuttongroup1);
            app.checkbox4.Text = 'Autre/Ne se prononce pas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Consent6_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end